classdef EcoGrow_V4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        Panel_11                        matlab.ui.container.Panel
        Image_9                         matlab.ui.control.Image
        NUSIGEM2019SimulationSoftwareLabel  matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        NotReadyLabel                   matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        WelcometoEcoGrowLabel           matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        WikiButton                      matlab.ui.control.Button
        FindoutmoreaboutourcontrolsystemsonourLabel  matlab.ui.control.Label
        ClickonatabonthelefthandsidetobeginLabel  matlab.ui.control.Label
        SgrSTab                         matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        ModelTypeDropDownLabel          matlab.ui.control.Label
        ModelTypeDropDown               matlab.ui.control.DropDown
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        SgrSInducermax5inputEditFieldLabel  matlab.ui.control.Label
        SgrSInducermax5inputEditField   matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        GlucosehalfsaturationconstantLabel  matlab.ui.control.Label
        GlucosehalfsaturationconstantEditField  matlab.ui.control.NumericEditField
        SgrSinducerhalfsaturationconstantLabel  matlab.ui.control.Label
        SgrSinducerhalfsaturationconstantEditField  matlab.ui.control.NumericEditField
        MaxgrowthrateEditFieldLabel     matlab.ui.control.Label
        MaxgrowthrateEditField          matlab.ui.control.NumericEditField
        hrLabel                         matlab.ui.control.Label
        nMLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        nMLabel_2                       matlab.ui.control.Label
        h1Label                         matlab.ui.control.Label
        GrowthknobSgrSLabel             matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        SimulateButton                  matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        GridLinesCheckBox               matlab.ui.control.CheckBox
        HoveroverthecurvetoviewthetraceLabel_2  matlab.ui.control.Label
        ClickonthecurvetoviewthetraceLabel  matlab.ui.control.Label
        ExitButton_2                    matlab.ui.control.Button
        HicAHicBTab                     matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        ResetButton_2                   matlab.ui.control.Button
        uMLabel                         matlab.ui.control.Label
        MLabel                          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        hrLabel_3                       matlab.ui.control.Label
        hrLabel_2                       matlab.ui.control.Label
        ToxinInducerEditFieldLabel      matlab.ui.control.Label
        ToxinInducerEditField           matlab.ui.control.EditField
        VariableSelectionDropDownLabel  matlab.ui.control.Label
        VariableSelectionDropDown       matlab.ui.control.DropDown
        AntitoxinInducerEditField_2Label  matlab.ui.control.Label
        AntitoxinInducerEditField       matlab.ui.control.EditField
        ToxinTimepointEditField_2Label  matlab.ui.control.Label
        ToxinTimepointEditField         matlab.ui.control.EditField
        AntitoxinTimepointEditFieldLabel  matlab.ui.control.Label
        AntitoxinTimepointEditField     matlab.ui.control.EditField
        DurationEditField_3Label        matlab.ui.control.Label
        DurationEditField_3             matlab.ui.control.NumericEditField
        hrLabel_5                       matlab.ui.control.Label
        GrowthSwitchHicAHicBLabel       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SimulateButton_2                matlab.ui.control.Button
        HoldPlotsCheckBox               matlab.ui.control.CheckBox
        Image_7                         matlab.ui.control.Image
        Panel_5                         matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        GridLinesCheckBox_2             matlab.ui.control.CheckBox
        ClickonthecurvetoviewthetraceLabel_2  matlab.ui.control.Label
        HoveroverthecurvetoviewthetraceLabel_4  matlab.ui.control.Label
        ExitButton_3                    matlab.ui.control.Button
        SurfacePlotTab                  matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        Parameter1DropDownLabel         matlab.ui.control.Label
        Parameter1DropDown              matlab.ui.control.DropDown
        Parameter2DropDownLabel         matlab.ui.control.Label
        Parameter2DropDown              matlab.ui.control.DropDown
        DurationEditField_2Label        matlab.ui.control.Label
        DurationEditField_2             matlab.ui.control.NumericEditField
        ResetButton_3                   matlab.ui.control.Button
        hrLabel_4                       matlab.ui.control.Label
        GrowthRecoveryEditFieldLabel    matlab.ui.control.Label
        GrowthRecoveryEditField         matlab.ui.control.NumericEditField
        GrowthArrestEditFieldLabel      matlab.ui.control.Label
        GrowthArrestEditField           matlab.ui.control.NumericEditField
        UsageofIPTGEditFieldLabel       matlab.ui.control.Label
        UsageofIPTGEditField            matlab.ui.control.NumericEditField
        GrowthReductionEditFieldLabel   matlab.ui.control.Label
        GrowthReductionEditField        matlab.ui.control.NumericEditField
        UsageofAraLabel                 matlab.ui.control.Label
        UsageofAraEditField             matlab.ui.control.NumericEditField
        SimulateButton_3                matlab.ui.control.Button
        SurfacePlotLabel                matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        Panel_8                         matlab.ui.container.Panel
        RedHighOutputBlueLowOutputLabel  matlab.ui.control.Label
        ExitButton_4                    matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        aTcVector = 0; % Description
        atcVectorLength =0;
        modelType = 0;
        simout = 0;
        
        toxinVector= 0;
        toxinVectorLength =0;
        
        antitoxinVector = 0;
        antitoxinVectorLength =0;
       
        toxinTimepointVector = 0;
        toxinTimepointVectorLength = 0;
        
        antitoxinTimepointVector = 0;
        antitoxinTimepointVectorLength = 0;
        
        selection = 1;
        
        simout2y =0;
        simout2t = 0;
        
        simout3 = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           SimulateButtonPushed(app, 1);
%            app.HoldPlotsCheckBox.Value = 1;
           SimulateButton_2Pushed(app, 1);
%            app.HoldPlotsCheckBox.Value = 0;
           SimulateButton_3Pushed(app, 1);
           dcm_obj = datacursormode(app.UIFigure);
            set(dcm_obj,'DisplayStyle','datatip',...
            'SnapToDataVertex','off','Enable','on')
           app.NotReadyLabel.Text = "Ready";
           app.NotReadyLabel.FontColor = 'green';
        end

        % Value changed function: ModelTypeDropDown
        function ModelTypeDropDownValueChanged(app, event)
            value = app.ModelTypeDropDown.Value;
            if (value == "Glucose (%)") 
                app.modelType = 0;
                
            elseif (value == "OD600") 
                app.modelType = 1;
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
         
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
     
            app.DurationEditField.Value = 17;
            app.SgrSInducermax5inputEditField.Value = "[0 1000]";
            app.GlucosehalfsaturationconstantEditField.Value = 0.31736719;
            app.SgrSinducerhalfsaturationconstantEditField.Value = 700.3832460001160;
            app.MaxgrowthrateEditField.Value = 1.0448e+00;
            SimulateButtonPushed(app, 1);


        end

        % Value changed function: GridLinesCheckBox
        function GridLinesCheckBoxValueChanged(app, event)
           if (app.GridLinesCheckBox.Value == 1) 
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
           else
           app.UIAxes.YGrid = 'off';
           app.UIAxes.XGrid = 'off';
               
           end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
              
            app.aTcVector = str2num(app.SgrSInducermax5inputEditField.Value);
            app.atcVectorLength = length(app.aTcVector);
            app.UIAxes.NextPlot = "replaceall";
 
            if (app.modelType == 1)
               
                app.simout = SgrS_Software_OD(app.DurationEditField.Value,app.aTcVector,app.GlucosehalfsaturationconstantEditField.Value ...
                   ,app.SgrSinducerhalfsaturationconstantEditField.Value,app.MaxgrowthrateEditField.Value);
                %              app.DebugEditField.Value = num2str(size(app.simout));   
                %              app.Debug2EditField.Value = num2str(size(app.aTcVector));
                
                for c = 1:1:app.atcVectorLength
                
                plot(app.UIAxes, app.simout(:,1), app.simout(:,c+1),'DisplayName',strcat(num2str(app.aTcVector(c)),'nM'),'LineWidth',2);
                legend(app.UIAxes)
                app.UIAxes.NextPlot = "add";
                title(app.UIAxes, 'OD_{600}','FontSize',20);
                xlabel(app.UIAxes,'Time(Hr)','fontweight', 'bold','FontSize', 12);
                ylabel(app.UIAxes,'OD_{600}','fontweight', 'bold','FontSize',12);
                set(app.UIAxes,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);
                end
        
            
           else
              
                   app.simout = SgrS_Software_Glucose(app.DurationEditField.Value,app.aTcVector,app.GlucosehalfsaturationconstantEditField.Value ...
                       ,app.SgrSinducerhalfsaturationconstantEditField.Value,app.MaxgrowthrateEditField.Value);
               for c = 1:1:app.atcVectorLength
     
                   plot(app.UIAxes, app.simout(:,1), app.simout(:,c+1),'DisplayName',strcat(num2str(app.aTcVector(c)),'nM'),'LineWidth',2);
                   legend(app.UIAxes)
                   app.UIAxes.NextPlot = "add";
                   title(app.UIAxes, 'Glucose (%) in media','FontSize',20);
                   xlabel(app.UIAxes,'Time (Hr)','fontweight', 'bold','FontSize', 12);
                   ylabel(app.UIAxes,'Glucose (%) in media','fontweight', 'bold','FontSize',12);    
                   set(app.UIAxes,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);    
               end  


            end
     
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.AntitoxinInducerEditField.Value = "0.0133" ;
            app.ToxinInducerEditField.Value= "[100 250 750 1250]";
            app.ToxinTimepointEditField.Value = "1";
            app.AntitoxinTimepointEditField.Value = "5";
            app.DurationEditField_3 = 24;
            SimulateButton_2Pushed(app, 1);
        end

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
                
%Selection 
            if (app.VariableSelectionDropDown.Value == "Toxin Inducer")
                app.selection = 1;
                
            elseif (app.VariableSelectionDropDown.Value == "Anti-toxin Inducer")
                app.selection = 2;
            
            elseif (app.VariableSelectionDropDown.Value == "Toxin Timepoint")
                app.selection = 3;
            
            elseif (app.VariableSelectionDropDown.Value == "Anti-toxin Timepoint")
                app.selection = 4;
            
            end


            app.toxinVector = str2num(app.ToxinInducerEditField.Value);
            app.toxinVectorLength = length(app.toxinVector);
 
                        
            app.antitoxinVector = str2num(app.AntitoxinInducerEditField.Value)
            app.antitoxinVectorLength = length(app.antitoxinVector);

          
            app.toxinTimepointVector = str2num(app.ToxinTimepointEditField.Value);
            app.toxinTimepointVectorLength = length(app.toxinTimepointVector);

            %Debug
 
        
            app.antitoxinTimepointVector = str2num(app.AntitoxinTimepointEditField.Value);
            app.antitoxinTimepointVectorLength = length(app.antitoxinTimepointVector);


               if (app.HoldPlotsCheckBox.Value == 1) 
                    app.UIAxes_2.NextPlot = "add";
               else 
                   app.UIAxes_2.NextPlot ='replaceall';
               end
                 [app.simout2t, app.simout2y] = HicA_HicB(app.toxinVector, app.antitoxinVector, ...
                     app.toxinTimepointVector, app.antitoxinTimepointVector, app.selection, app.DurationEditField_3.Value);

                 
                if (app.selection == 1) 
                for c = 1:1:app.toxinVectorLength
                    plot(app.UIAxes_2, app.simout2t(:,1), app.simout2y(:,c),'DisplayName',strcat(num2str(app.toxinVector(c)),'uM'),'LineWidth',2);
                    legend(app.UIAxes_2)
                    app.UIAxes_2.NextPlot = "add";
                    title(app.UIAxes_2, 'OD_{600}','FontSize',20);
                    xlabel(app.UIAxes_2,'Time(Hr)','fontweight', 'bold','FontSize', 12);
                    ylabel(app.UIAxes_2,'OD_{600}','fontweight', 'bold','FontSize',12);
                    set(app.UIAxes_2,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);                    
                end 
                elseif (app.selection == 2) 
                    for c = 1:1:app.antitoxinVectorLength
                        plot(app.UIAxes_2, app.simout2t(:,1), app.simout2y(:,c),'DisplayName',strcat(num2str(app.antitoxinVector(c)),'M'),'LineWidth',2);
                        legend(app.UIAxes_2)
                        app.UIAxes_2.NextPlot = "add";
                        title(app.UIAxes_2, 'OD_{600}','FontSize',20);
                        xlabel(app.UIAxes_2,'Time(Hr)','fontweight', 'bold','FontSize', 12);
                        ylabel(app.UIAxes_2,'OD_{600}','fontweight', 'bold','FontSize',12);
                        set(app.UIAxes_2,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);                    
                    end 
                elseif (app.selection == 3) 
                for c = 1:1:app.toxinTimepointVectorLength
                    plot(app.UIAxes_2, app.simout2t(:,1), app.simout2y(:,c),'DisplayName',strcat(num2str(app.toxinTimepointVector(c)),'hr'),'LineWidth',2);
                    legend(app.UIAxes_2)
                    app.UIAxes_2.NextPlot = "add";
                    title(app.UIAxes_2, 'OD_{600}','FontSize',20);
                    xlabel(app.UIAxes_2,'Time(Hr)','fontweight', 'bold','FontSize', 12);
                    ylabel(app.UIAxes_2,'OD_{600}','fontweight', 'bold','FontSize',12);
                    set(app.UIAxes_2,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);                
                end 
                elseif (app.selection == 4) 
                    for c = 1:1:app.antitoxinTimepointVectorLength
                        plot(app.UIAxes_2, app.simout2t(:,1), app.simout2y(:,c),'DisplayName',strcat(num2str(app.antitoxinTimepointVector(c)),'hr'),'LineWidth',2);
                        legend(app.UIAxes_2)
                        app.UIAxes_2.NextPlot = "add";
                        title(app.UIAxes_2, 'OD_{600}','FontSize',20);
                        xlabel(app.UIAxes_2,'Time(Hr)','fontweight', 'bold','FontSize', 12);
                        ylabel(app.UIAxes_2,'OD_{600}','fontweight', 'bold','FontSize',12);
                        set(app.UIAxes_2,'box', 'off', 'linewidth',1,'Fontweight','bold','FontSize',12);                    
                    end 

                end
%             datacursormode on;
        end

        % Value changed function: HoldPlotsCheckBox
        function HoldPlotsCheckBoxValueChanged(app, event)
       
              
        end

        % Value changed function: VariableSelectionDropDown
        function VariableSelectionDropDownValueChanged(app, event)
            value = app.VariableSelectionDropDown.Value;

        
        end

        % Callback function
        function EditField2ValueChanged(app, event)
            
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.Parameter1DropDown.Value = "Ara";
            app.Parameter2DropDown.Value = "IPTG";
            app.DurationEditField_2.Value = 1000;
            app.GrowthReductionEditField.Value = 1;
            app.GrowthRecoveryEditField.Value = 1;
            app.GrowthArrestEditField.Value = 0;
            app.UsageofIPTGEditField.Value = 0.5;
            app.UsageofAraEditField.Value = 0.5;
            SimulateButton_3Pushed(app , 1);
            
        end

        % Button pushed function: SimulateButton_3
        function SimulateButton_3Pushed(app, event)
           app.simout3 = Input_Surface_Response(app.Parameter1DropDown.Value, app.Parameter2DropDown.Value, app.GrowthReductionEditField.Value, ...
               app.GrowthRecoveryEditField.Value, app.GrowthArrestEditField.Value, app.UsageofIPTGEditField.Value, app.UsageofAraEditField.Value, ...
               app.DurationEditField_2.Value);
           
            h = heatmap(app.Panel_10, app.simout3, app.Parameter1DropDown.Value, app.Parameter2DropDown.Value, 'ColorVariable', 'Objective', ...
                'CellLabelColor','none', 'CellLabelFormat','%.2f');
            h.Title ='Values of Objective Functions';
            h.FontSize  = 18;
            h.FontColor = 'Black';
            colormap(h,jet);
            h.YDisplayLabels = format_axis(h.YDisplayLabels);
            h.XDisplayLabels = format_axis(h.XDisplayLabels);


        end

        % Button pushed function: WikiButton
        function WikiButtonPushed(app, event)
             dos('explorer https://2019.igem.org/Team:NUS_Singapore/Description#ControlSystems');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close all force ;
        end

        % Button pushed function: ExitButton_2
        function ExitButton_2Pushed(app, event)
            close all force ;
        end

        % Button pushed function: ExitButton_3
        function ExitButton_3Pushed(app, event)
            close all force ;
        end

        % Button pushed function: ExitButton_4
        function ExitButton_4Pushed(app, event)
            close all force ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.Panel_11 = uipanel(app.WelcomeTab);
            app.Image_9 = uiimage(app.Panel_11);
            app.Image_9.ImageSource = 'WhatsApp Image 2019-10-17 at 4.23.57 PM.jpeg';
            app.NUSIGEM2019SimulationSoftwareLabel = uilabel(app.Panel_11);
            app.NUSIGEM2019SimulationSoftwareLabel.Text = {'NUS IGEM 2019'; 'Simulation Software'};
            app.ExitButton = uibutton(app.Panel_11, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.NotReadyLabel = uilabel(app.Panel_11);
            app.NotReadyLabel.Text = 'Not Ready';
            app.Panel_12 = uipanel(app.WelcomeTab);
            app.WelcometoEcoGrowLabel = uilabel(app.Panel_12);
            app.WelcometoEcoGrowLabel.Text = 'Welcome to E.coGrow';
            app.Label_7 = uilabel(app.Panel_12);
            app.Label_7.Text = {'Welcome to E.co Grow, a powerful software tool that simulates bacterial growth without running experiments in the wet lab.'; ''; 'The foundation of this software lies in the models of the HicA-HicB and SgrS systems which have been built using our characterization data. On'; 'the basis of a few inputs specified by users, our software can predict  bacterial growth trends and offer experimental design recommendations'; 'to suit their purpose. '; ''; 'Instructions for using E.co Grow:'; '1. The default simulation is our optimized system. Click the ‘Reset’ button to show the default simulation with our optimized parameter values.'; '2. The user can input different parameter values to simulate bacterial growth trends in different experimental design conditions. '; '3 Click the ‘Simulate’ button to generate bacteria growth trends with your parameter values.'; '4. The explanation of the parameters are as follows:'; ''; 'Growth Switch (HicA-HicB)'; '1) Inducer (IPTG) concentration for toxin.'; 'A larger value will result in a more pronounced growth arrest as the toxin has a repressive effect on growth. '; '2) Inducer (Arabinose) concentration for antitoxin. '; 'A larger value will result in a greater growth resumption as the antitoxin can rescue cells from a growth-arrested mode. '; '3) Induction timepoint of toxin. This is the timepoint at which the inducer for toxin is added to the cells. '; '4) Induction timepoint of antitoxin. This is the timepoint at which the inducer for antitoxin is added to the cells.'; ''; 'Growth Knob (SgrS)'; '1) Inducer (aTc) concentration for SgrS. A larger value will result in slower growth as SgrS has a repressive effect on growth.'; '2) Glucose half saturation constant for growth. This represents the sensitivity of growth to glucose, and depends on the strain of bacteria '; 'used. A larger value represents a lower sensitivity of the bacteria’s growth to glucose, and will result in slower growth.'; '3) Inducer half saturation constant for growth. This is the sensitivity of growth to the inducer for SgrS, and it depends on the'; ' type of inducer used. A smaller value represents a greater synthesis rate of SgrS, and will result in slower growth.'; '4) Maximum rate of cell growth. This depends on the strain of bacteria used. A smaller value will result in slower growth.'; ''; 'Surface Plot'; '1) Choose 2 parameters of your choice.'; '2) Choose the weight of each criterion. Note that Growth arrest can only be 1 or 0.'; 'Variables: nOD, Ktox, ntox, Kb, synt, Ki, degt, syna, Ka, dega, ti, IPTG, ta, Ara, OD_init'; 'Weights: w_reduction, w_recovery, w_arrest, w_IPTG, w_Ara'; ''; ''};
            app.WikiButton = uibutton(app.Panel_12, 'push');
            app.WikiButton.ButtonPushedFcn = createCallbackFcn(app, @WikiButtonPushed, true);
            app.WikiButton.Text = 'Wiki!';
            app.FindoutmoreaboutourcontrolsystemsonourLabel = uilabel(app.Panel_12);
            app.FindoutmoreaboutourcontrolsystemsonourLabel.Text = 'Find out more about our control systems on our ';
            app.ClickonatabonthelefthandsidetobeginLabel = uilabel(app.Panel_12);
            app.ClickonatabonthelefthandsidetobeginLabel.Text = 'Click on a tab on the left hand side to begin!';
            app.SgrSTab = uitab(app.TabGroup);
            app.SgrSTab.Title = 'SgrS';
            app.Panel_2 = uipanel(app.SgrSTab);
            app.Panel = uipanel(app.Panel_2);
            app.Panel.Title = 'Panel';
            app.ModelTypeDropDownLabel = uilabel(app.Panel);
            app.ModelTypeDropDownLabel.Text = 'Model Type';
            app.ModelTypeDropDown = uidropdown(app.Panel);
            app.ModelTypeDropDown.Items = {'Glucose (%)', 'OD600'};
            app.ModelTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelTypeDropDownValueChanged, true);
            app.ModelTypeDropDown.Value = 'Glucose (%)';
            app.DurationEditFieldLabel = uilabel(app.Panel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Panel, 'numeric');
            app.DurationEditField.Value = 17;
            app.SgrSInducermax5inputEditFieldLabel = uilabel(app.Panel);
            app.SgrSInducermax5inputEditFieldLabel.Text = 'SgrS Inducer (max 5 input)';
            app.SgrSInducermax5inputEditField = uieditfield(app.Panel, 'text');
            app.SgrSInducermax5inputEditField.Value = '[0 1000]';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GlucosehalfsaturationconstantLabel = uilabel(app.Panel);
            app.GlucosehalfsaturationconstantLabel.Text = 'Glucose half-saturation constant';
            app.GlucosehalfsaturationconstantEditField = uieditfield(app.Panel, 'numeric');
            app.GlucosehalfsaturationconstantEditField.ValueDisplayFormat = '%.3f';
            app.GlucosehalfsaturationconstantEditField.Value = 0.31736719;
            app.SgrSinducerhalfsaturationconstantLabel = uilabel(app.Panel);
            app.SgrSinducerhalfsaturationconstantLabel.Text = {'SgrS inducer '; 'half-saturation constant'};
            app.SgrSinducerhalfsaturationconstantEditField = uieditfield(app.Panel, 'numeric');
            app.SgrSinducerhalfsaturationconstantEditField.ValueDisplayFormat = '%.3f';
            app.SgrSinducerhalfsaturationconstantEditField.Value = 700.383246000116;
            app.MaxgrowthrateEditFieldLabel = uilabel(app.Panel);
            app.MaxgrowthrateEditFieldLabel.Text = 'Max growth rate';
            app.MaxgrowthrateEditField = uieditfield(app.Panel, 'numeric');
            app.MaxgrowthrateEditField.ValueDisplayFormat = '%.3f';
            app.MaxgrowthrateEditField.Value = 1.0448;
            app.hrLabel = uilabel(app.Panel);
            app.hrLabel.Text = 'hr';
            app.nMLabel = uilabel(app.Panel);
            app.nMLabel.Text = 'nM';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '%';
            app.nMLabel_2 = uilabel(app.Panel);
            app.nMLabel_2.Text = 'nM';
            app.h1Label = uilabel(app.Panel);
            app.h1Label.Text = 'h^-1';
            app.GrowthknobSgrSLabel = uilabel(app.Panel_2);
            app.GrowthknobSgrSLabel.Text = 'Growth knob (SgrS)';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = {'Our growth knob model predicts cell growth and glucose in, media '; 'trends over a range of SgrS inducer concentrations. Max growth '; 'rate, SgrS half-saturation constant (k_atc), and Glucose half-'; 'saturation constant (k_G) are parameters of the model that can'; 'be varied through experimental design to modify the trends.'};
            app.Image_6 = uiimage(app.Panel_2);
            app.Image_6.ImageSource = 'WhatsApp Image 2019-10-17 at 4.23.57 PM.jpeg';
            app.SimulateButton = uibutton(app.Panel_2, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Panel_3 = uipanel(app.SgrSTab);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.GridLinesCheckBox = uicheckbox(app.Panel_3);
            app.GridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridLinesCheckBoxValueChanged, true);
            app.GridLinesCheckBox.Text = 'Grid Lines';
            app.HoveroverthecurvetoviewthetraceLabel_2 = uilabel(app.Panel_3);
            app.HoveroverthecurvetoviewthetraceLabel_2.Text = 'To download, reset, or zoom, use the buttons located in the top right of the plot.';
            app.ClickonthecurvetoviewthetraceLabel = uilabel(app.Panel_3);
            app.ClickonthecurvetoviewthetraceLabel.Text = 'Click on the curve to view the trace.';
            app.ExitButton_2 = uibutton(app.Panel_3, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_2Pushed, true);
            app.ExitButton_2.Text = 'Exit';
            app.HicAHicBTab = uitab(app.TabGroup);
            app.HicAHicBTab.Title = 'HicA-HicB';
            app.Panel_4 = uipanel(app.HicAHicBTab);
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = 'Panel';
            app.ResetButton_2 = uibutton(app.Panel_6, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.uMLabel = uilabel(app.Panel_6);
            app.uMLabel.Text = 'uM';
            app.MLabel = uilabel(app.Panel_6);
            app.MLabel.Text = 'M';
            app.Label_2 = uilabel(app.Panel_6);
            app.Label_2.Text = '';
            app.hrLabel_3 = uilabel(app.Panel_6);
            app.hrLabel_3.Text = 'hr';
            app.hrLabel_2 = uilabel(app.Panel_6);
            app.hrLabel_2.Text = 'hr';
            app.ToxinInducerEditFieldLabel = uilabel(app.Panel_6);
            app.ToxinInducerEditFieldLabel.Text = 'Toxin Inducer ';
            app.ToxinInducerEditField = uieditfield(app.Panel_6, 'text');
            app.ToxinInducerEditField.Value = '[100 250 750 1250]';
            app.VariableSelectionDropDownLabel = uilabel(app.Panel_6);
            app.VariableSelectionDropDownLabel.Text = 'Variable Selection';
            app.VariableSelectionDropDown = uidropdown(app.Panel_6);
            app.VariableSelectionDropDown.Items = {'Toxin Inducer', 'Anti-toxin Inducer', 'Toxin Timepoint', 'Anti-toxin Timepoint'};
            app.VariableSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableSelectionDropDownValueChanged, true);
            app.VariableSelectionDropDown.Value = 'Toxin Inducer';
            app.AntitoxinInducerEditField_2Label = uilabel(app.Panel_6);
            app.AntitoxinInducerEditField_2Label.Text = 'Anti-toxin Inducer';
            app.AntitoxinInducerEditField = uieditfield(app.Panel_6, 'text');
            app.AntitoxinInducerEditField.Value = '0.0133';
            app.ToxinTimepointEditField_2Label = uilabel(app.Panel_6);
            app.ToxinTimepointEditField_2Label.Text = 'Toxin Timepoint';
            app.ToxinTimepointEditField = uieditfield(app.Panel_6, 'text');
            app.ToxinTimepointEditField.Value = '1';
            app.AntitoxinTimepointEditFieldLabel = uilabel(app.Panel_6);
            app.AntitoxinTimepointEditFieldLabel.Text = 'Anti-toxin Timepoint';
            app.AntitoxinTimepointEditField = uieditfield(app.Panel_6, 'text');
            app.AntitoxinTimepointEditField.Value = '5';
            app.DurationEditField_3Label = uilabel(app.Panel_6);
            app.DurationEditField_3Label.Text = 'Duration';
            app.DurationEditField_3 = uieditfield(app.Panel_6, 'numeric');
            app.DurationEditField_3.Value = 24;
            app.hrLabel_5 = uilabel(app.Panel_6);
            app.hrLabel_5.Text = 'hr';
            app.GrowthSwitchHicAHicBLabel = uilabel(app.Panel_4);
            app.GrowthSwitchHicAHicBLabel.Text = 'Growth Switch (HicA-HicB)';
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = {'This section of the E.co Grow software allows one to run hundreds '; 'of simulations pertaining to growth trends by specifying inputs such '; 'as Inducer concentrations and, Induction timepoints for the toxin '; 'as well as antitoxin.'};
            app.SimulateButton_2 = uibutton(app.Panel_4, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.HoldPlotsCheckBox = uicheckbox(app.Panel_4);
            app.HoldPlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldPlotsCheckBoxValueChanged, true);
            app.HoldPlotsCheckBox.Text = 'Hold Plots';
            app.Image_7 = uiimage(app.Panel_4);
            app.Image_7.ImageSource = 'WhatsApp Image 2019-10-17 at 4.23.57 PM.jpeg';
            app.Panel_5 = uipanel(app.HicAHicBTab);
            app.UIAxes_2 = uiaxes(app.Panel_5);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.GridLinesCheckBox_2 = uicheckbox(app.Panel_5);
            app.GridLinesCheckBox_2.Text = 'Grid Lines';
            app.ClickonthecurvetoviewthetraceLabel_2 = uilabel(app.Panel_5);
            app.ClickonthecurvetoviewthetraceLabel_2.Text = 'Click on the curve to view the trace.';
            app.HoveroverthecurvetoviewthetraceLabel_4 = uilabel(app.Panel_5);
            app.HoveroverthecurvetoviewthetraceLabel_4.Text = 'To download, reset, or zoom, use the buttons located in the top right of the plot.';
            app.ExitButton_3 = uibutton(app.Panel_5, 'push');
            app.ExitButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_3Pushed, true);
            app.ExitButton_3.Text = 'Exit';
            app.SurfacePlotTab = uitab(app.TabGroup);
            app.SurfacePlotTab.Title = 'Surface Plot';
            app.Panel_7 = uipanel(app.SurfacePlotTab);
            app.Panel_9 = uipanel(app.Panel_7);
            app.Panel_9.Title = 'Panel';
            app.Parameter1DropDownLabel = uilabel(app.Panel_9);
            app.Parameter1DropDownLabel.Text = 'Parameter 1';
            app.Parameter1DropDown = uidropdown(app.Panel_9);
            app.Parameter1DropDown.Items = {'mu_max', 'OD_max', 'OD_min', 'nOD', 'Ktox', 'ntox', 'Kb', 'synt', 'Ki', 'degt', 'syna', 'Ka', 'dega', 'ti', 'IPTG', 'ta', 'Ara', 'OD_init'};
            app.Parameter1DropDown.Value = 'IPTG';
            app.Parameter2DropDownLabel = uilabel(app.Panel_9);
            app.Parameter2DropDownLabel.Text = 'Parameter 2';
            app.Parameter2DropDown = uidropdown(app.Panel_9);
            app.Parameter2DropDown.Items = {'mu_max', 'OD_max', 'OD_min', 'nOD', 'Ktox', 'ntox', 'Kb', 'synt', 'Ki', 'degt', 'syna', 'Ka', 'dega', 'ti', 'IPTG', 'ta', 'Ara', 'OD_init'};
            app.Parameter2DropDown.Value = 'Ara';
            app.DurationEditField_2Label = uilabel(app.Panel_9);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.Panel_9, 'numeric');
            app.DurationEditField_2.Value = 1000;
            app.ResetButton_3 = uibutton(app.Panel_9, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.hrLabel_4 = uilabel(app.Panel_9);
            app.hrLabel_4.Text = 'hr';
            app.GrowthRecoveryEditFieldLabel = uilabel(app.Panel_9);
            app.GrowthRecoveryEditFieldLabel.Text = 'Growth Recovery';
            app.GrowthRecoveryEditField = uieditfield(app.Panel_9, 'numeric');
            app.GrowthRecoveryEditField.Value = 1;
            app.GrowthArrestEditFieldLabel = uilabel(app.Panel_9);
            app.GrowthArrestEditFieldLabel.Text = 'Growth Arrest';
            app.GrowthArrestEditField = uieditfield(app.Panel_9, 'numeric');
            app.UsageofIPTGEditFieldLabel = uilabel(app.Panel_9);
            app.UsageofIPTGEditFieldLabel.Text = 'Usage of IPTG';
            app.UsageofIPTGEditField = uieditfield(app.Panel_9, 'numeric');
            app.UsageofIPTGEditField.Value = 0.5;
            app.GrowthReductionEditFieldLabel = uilabel(app.Panel_9);
            app.GrowthReductionEditFieldLabel.Text = 'Growth Reduction';
            app.GrowthReductionEditField = uieditfield(app.Panel_9, 'numeric');
            app.GrowthReductionEditField.Value = 1;
            app.UsageofAraLabel = uilabel(app.Panel_9);
            app.UsageofAraLabel.Text = 'Usage of Ara';
            app.UsageofAraEditField = uieditfield(app.Panel_9, 'numeric');
            app.UsageofAraEditField.Value = 0.5;
            app.SimulateButton_3 = uibutton(app.Panel_7, 'push');
            app.SimulateButton_3.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_3Pushed, true);
            app.SimulateButton_3.Text = 'Simulate';
            app.SurfacePlotLabel = uilabel(app.Panel_7);
            app.SurfacePlotLabel.Text = 'Surface Plot';
            app.Label_6 = uilabel(app.Panel_7);
            app.Label_6.Text = {'The algorithm accepts 2 parameters as inputs which are then varied'; 'to create a 2-D array of parameter sets.The ODEs in the model are '; 'then integrated using the parameter sets and the output is evaluated'; 'using an objective function that takes into account five criteria. '; 'Finally a heatmap of the objective values is plotted.'};
            app.Image_8 = uiimage(app.Panel_7);
            app.Image_8.ImageSource = 'WhatsApp Image 2019-10-17 at 4.23.57 PM.jpeg';
            app.Panel_8 = uipanel(app.SurfacePlotTab);
            app.RedHighOutputBlueLowOutputLabel = uilabel(app.Panel_8);
            app.RedHighOutputBlueLowOutputLabel.Text = {'Red: High Output'; 'Blue: Low Output'};
            app.ExitButton_4 = uibutton(app.Panel_8, 'push');
            app.ExitButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_4Pushed, true);
            app.ExitButton_4.Text = 'Exit';
            app.Panel_10 = uipanel(app.SurfacePlotTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcoGrow_V4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end